classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        remotoLabel               matlab.ui.control.Label
        operarreomotoSwitch       matlab.ui.control.Switch
        operarreomotoSwitchLabel  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        recButton                 matlab.ui.control.Button
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        CirculoLabel              matlab.ui.control.Label
        ComodoroLabel             matlab.ui.control.Label
        LuzLabel                  matlab.ui.control.Label
        OkButton                  matlab.ui.control.Button
        BenderLabel               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        STARTButton               matlab.ui.control.StateButton
        VozPanel                  matlab.ui.container.Panel
        OptionsTextArea           matlab.ui.control.TextArea
        RECButton                 matlab.ui.control.StateButton
        ProyectoModulo3Label      matlab.ui.control.Label
        ImagenPanel               matlab.ui.container.Panel
        Label_2                   matlab.ui.control.Label
        FOTOButton                matlab.ui.control.StateButton
        ManualPanel               matlab.ui.container.Panel
        CirculoButton             matlab.ui.control.StateButton
        PentgonoButton_5          matlab.ui.control.StateButton
        EstrellaButton_3          matlab.ui.control.StateButton
        CreatedByColmenaresLampreaandRodriguezBeltranLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n=10
        L1=4;
        L2=12;
        L3=3;
        L4=12;
        red_audio
        red_robot
        red_gestos
        audio
        a
        s1
        s2
        Bender
        PE=[-10.46 -11.44 -14 -12 -13 -10.8 -8.8 -9.43 -7.21 -9.8;...
            11.8 9.38 9.56 7.8 5.4 7 5.17 7.7 9.10 9.27];

        % Channel ID to read data from
        readChannelID = 2013619;
        % Channel Read API Key
        readAPIKey = 'E9C9I4JBQOEUSETJ';
        % Channel Read API Key
        writeAPIKey = 'G28HZOKZQFENE9K6';
        U = matlab.net.URI('http://169.51.207.249:30664');
        estado='inicio';
        data
        

    end
    
    
    methods (Access = public)
        
        function inversaW(app,VectorX,VectorY)
            Ang_RED=sim(app.red_robot,[VectorX;VectorY]);
            Pxn1=zeros(1,length(VectorX));
            Pyn1=ones(1,length(VectorY));
            for j=1:1:app.n
                MTH=app.Bender.fkine(Ang_RED(:,j));
                Pxn1(1,j)=MTH(1,4);
                Pyn1(1,j)=MTH(2,4);
                writePosition(app.s1,(Ang_RED(1,j)/pi));
                writePosition(app.s2,(Ang_RED(2,j)/pi));
                pause(0.2);
            end
            
        end

        function  figuras(app,p)
            %p=2 %poligono
            %p=1 %estrella>}
            if (p<3)
                for i=1:p:app.n

                    if (i > app.n-p)
                        VectorX=linspace(app.PE(1,i),app.PE(1,1),app.n);
                        VectorY=linspace(app.PE(2,i),app.PE(2,1),app.n);
                    else
                        VectorX=linspace(app.PE(1,i),app.PE(1,i+p),app.n);
                        VectorY=linspace(app.PE(2,i),app.PE(2,i+p),app.n);
                    end
                    if (p==1)
                        UpData(app,'haciendo estrella',2)
                    else
                        UpData(app,'haciendo poligono',2)
                    end
                    inversaW(app,VectorX,VectorY)
                    
                end
            else
                UpData(app,'haciendo Circulo',2)
                k=0.3;
                j=0;
                for i=-3 : 0.2 : 3
                    j=j+1;
                    y1 = sqrt(9-(i^2));
                    VectorX1(j)=-10+i;
                    VectorY1(j)=8+y1;
                end
                
                Ang_RED=sim(app.red_robot,[VectorX1;VectorY1]);
                for i=1:1:length(VectorX1)
                    MTH=app.Bender.fkine(Ang_RED(:,i));
                    Pxn1(i)=MTH(1,4);
                    Pyn1(i)=MTH(2,4);
                    writePosition(app.s1,(Ang_RED(1,i)/pi));
                    writePosition(app.s2,(Ang_RED(2,i)/pi));
                    %plot(app.UIAxes, Pxn1(i),Pyn1(i),'b*--');
                    pause(k);
                end


                plot(app.UIAxes, Pxn1,Pyn1,'b*--');
                    j=0;
                    for i=3 : -0.2 : -3
                        j=j+1;
                        y1 = -sqrt(9-(i^2));
                        VectorX1(j)=-10+i;
                        VectorY1(j)=8+y1;
                    end
                                        
                    Ang_RED=sim(app.red_robot,[VectorX1;VectorY1]);
                    for i=1:1:length(VectorX1)
                    MTH=app.Bender.fkine(Ang_RED(:,i));
                    Pxn1(i)=MTH(1,4);
                    Pyn1(i)=MTH(2,4);
                    writePosition(app.s1,(Ang_RED(1,i)/pi));
                    writePosition(app.s2,(Ang_RED(2,i)/pi));

                    pause(k);
                    end

                    plot(app.UIAxes, Pxn1,Pyn1,'b*--');
%                 j=0;
%                 for i=-3 : 0.2 : 3
%                     j=j+1;
%                     y1 = sqrt(9-(i^2));
%                     VectorX1(j)=-10+i;
%                     VectorY1(j)=8+y1;
%                 end
%                 %             inversaW(app,VectorX1,VectorY1)
% 
%                 for i=3 : -0.2 : -3
%                     j=j+1;
%                     y1 = -sqrt(9-(i^2));
%                     VectorX1(j)=-10+i;
%                     VectorY1(j)=8+y1;
%                 end
%                 plot(app.UIAxes,VectorX1,VectorY1)
% %                 inversaW(app,VectorX1,VectorY1)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%5
                
            end
        end
        
        function UpData(app,dataW,field)
            thingSpeakWrite(app.readChannelID,'Fields',field,'Values',{dataW},'WriteKey',app.writeAPIKey);
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EstrellaButton_3
        function EstrellaButton_3ValueChanged(app, event)
           figuras(app,1);
           
            %estrellaButtonManual;
        end

        % Value changed function: PentgonoButton_5
        function PentgonoButton_5ValueChanged(app, event)
            figuras(app,2);
        end

        % Value changed function: CirculoButton
        function CirculoButtonValueChanged(app, event)
           figuras(app,3)
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
        clear port;
        clear app.a
        app.a = arduino('COM7', 'Uno', 'Libraries', 'Servo');
        app.s1 =servo(app.a,'D5');
        app.s2= servo(app.a,'D6');
        pause(0.5)
        app.Label.Text="CARGANDO...";
        B(1)=Link('revolute','d',app.L1,'alpha',0,'a',app.L2,'offset',0);
        B(2)=Link('revolute','d',app.L3,'alpha',0,'a',app.L4,'offset',0);
        app.Bender=SerialLink(B,'name','El Bender');
        pause(0.5)
        app.Label.Text="bender";
        load ("Intento2.mat","Red2R")
        app.red_robot= Red2R;
        load ("Red_Voz4.mat","Voice")
        app.red_audio= Voice;
        load ("GestosNET3.mat","RedGestos")
        app.red_gestos= RedGestos;
        app.Label.Text="READY!";

        writeDigitalPin(app.a,'D2',0);
        writeDigitalPin(app.a,'D3',0);
          app.STARTButton.FontColor=[0 1 0];
         % Definicon de url
         app.U.Query = matlab.net.QueryParameter('payload',app.estado);
         app.U.Path = 'prueba';
         UpData(app,app.estado,2)
         webread(app.U);


        end

        % Value changed function: FOTOButton
        function FOTOButtonValueChanged(app, event)
            UpData(app,'inicio de captura de imagen',2) 
            app.Label_2.Text="";
             pause(1);
            QueGestos=[0 0 0 0 0]';
            for i=1 : 1 : 5
                cam = webcam();
                cam.Resolution='176x144';
                data = snapshot(cam);
                [guantebin,guante]=soloGuante(data);
                guantebin=bwareaopen(guantebin,800);
                guantebin=imfill(guantebin,'holes');
                imshow(guantebin);
                pause(0.2);
                QueGesto=sim(app.red_gestos,guantebin(:))
                imshow(guantebin);
                clear('cam');
                    QueGestos=QueGestos+QueGesto;
               
            end
            pos=0;
            [valor,pos]=max(QueGestos);

            switch pos
                case 1
                    app.Label_2.Text=["estrella",num2str(valor)];
                    figuras(app,1);
                    

                case 2
                    app.Label_2.Text=["pentagono",num2str(valor)];
                    figuras(app,2);
                case 3
                    app.Label_2.Text=["circulo",num2str(valor)];
                    figuras(app,3);
                case 4
                    app.Label_2.Text=["caso 4",num2str(valor)];
                    writeDigitalPin(app.a,'D2',1)
                    pause(1)
                    writeDigitalPin(app.a,'D2',0);
                case 5
                    app.Label_2.Text=['caso 5',num2str(valor)];
                    writeDigitalPin(app.a,'D3',1);
                    pause(1)
                    writeDigitalPin(app.a,'D3',0);

            end
            app.OkButton.FontColor=[0 1 0];

   
        end

        % Value changed function: RECButton
        function RECButtonValueChanged(app, event)
%             value = app.RECButton.Value;
%             app.audio=
                UpData(app,'inicio de captura de voz',2)
               app.OkButton.FontColor=[0.8 0.8 0.8];
             Voice=app.red_audio;
              app.Panel.Visible="on";
              app.Panel.Title="Grabacion";
             H=0;
             for jj =1 : 2
                 REC=audiorecorder();
                app.Label_8.Text="INICIO";
%                  disp(['INICIO DE LA GRABACIÓN # ',num2str(1)])
                 recordblocking(REC,3);
%                  disp(['FIN DE LA GRABACIÓN #',num2str(1)])
                app.Label_8.Text="FIN";
                pause(0.2)
                 Audio=getaudiodata(REC);
                 [ P1,f ] = Get_FFT( Audio, 8000, length(Audio));


                 for i=1 : 3000
                     P12(i)= P1(i) / max (P1);
                 end

                 zz=0;

                 Audio1=zeros(24000,1);
                 for xx =1 : length(Audio)
                     if Audio(xx)>0.1 || zz>0
                         zz=zz+1;
                         Audio1(zz)=Audio(xx);
                     end
                 end

                 a = [0.1 -0.95];
                 b = [1 -0.99];
                 Audio1=filter(a,b,Audio1);

                 for i=1 : 5000
                     Audio1(i)= Audio1(i) / max (Audio1);
                 end


                 zz=0;
                 for i=3000 : 8000
                     zz=zz+1;
                     P12(i)= abs(Audio1(zz));
                 end


                 Salida=sim(Voice,P12')
                 [a,b]=max(Salida)
                 
             end

            switch b
                case 1
                    app.Label_8.Text="estrella";
                    figuras(app,1);            

                case 2
                    app.Label_8.Text="pentagono";
                    figuras(app,2);                    
                case 3
                    app.Label_8.Text="circulo";
                    figuras(app,3);
                    
                case 4
                    app.Label_8.Text="caso 4";
                    writeDigitalPin(app.a,'D2',1);
                    pause(1)
                    writeDigitalPin(app.a,'D2',0);
                    

                case 5
                    app.Label_8.Text="caso 5";
                    writeDigitalPin(app.a,'D3',1);
                    pause(1)
                    writeDigitalPin(app.a,'D3',0);
                    
            end
            app.OkButton.FontColor=[0 1 0];

        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.Panel.Visible="off";
            
        end

        % Button pushed function: recButton
        function recButtonPushed(app, event)
            app.RECButton.Value= true;
            app.RECButton.Value= false;  
        end

        % Value changed function: operarreomotoSwitch
        function operarreomotoSwitchValueChanged(app, event)
            value = app.operarreomotoSwitch.Value;
            app.remotoLabel.Text= value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ecci.jpg');
            app.CreatedByColmenaresLampreaandRodriguezBeltranLabel = uilabel(app.UIFigure);
            app.CreatedByColmenaresLampreaandRodriguezBeltranLabel.Text = '       Created By: Colmenares, Lamprea and Rodriguez Beltran';
            app.ManualPanel = uipanel(app.UIFigure);
            app.ManualPanel.Title = 'Manual';
            app.EstrellaButton_3 = uibutton(app.ManualPanel, 'state');
            app.EstrellaButton_3.ValueChangedFcn = createCallbackFcn(app, @EstrellaButton_3ValueChanged, true);
            app.EstrellaButton_3.Text = 'Estrella';
            app.PentgonoButton_5 = uibutton(app.ManualPanel, 'state');
            app.PentgonoButton_5.ValueChangedFcn = createCallbackFcn(app, @PentgonoButton_5ValueChanged, true);
            app.PentgonoButton_5.Text = 'Pentágono';
            app.CirculoButton = uibutton(app.ManualPanel, 'state');
            app.CirculoButton.ValueChangedFcn = createCallbackFcn(app, @CirculoButtonValueChanged, true);
            app.CirculoButton.Text = 'Circulo';
            app.ImagenPanel = uipanel(app.UIFigure);
            app.ImagenPanel.Title = 'Imagen';
            app.FOTOButton = uibutton(app.ImagenPanel, 'state');
            app.FOTOButton.ValueChangedFcn = createCallbackFcn(app, @FOTOButtonValueChanged, true);
            app.FOTOButton.Text = 'FOTO';
            app.Label_2 = uilabel(app.ImagenPanel);
            app.Label_2.Text = '';
            app.ProyectoModulo3Label = uilabel(app.UIFigure);
            app.ProyectoModulo3Label.Text = 'Proyecto Modulo 3';
            app.VozPanel = uipanel(app.UIFigure);
            app.VozPanel.Title = 'Voz';
            app.RECButton = uibutton(app.VozPanel, 'state');
            app.RECButton.ValueChangedFcn = createCallbackFcn(app, @RECButtonValueChanged, true);
            app.RECButton.Text = 'REC';
            app.OptionsTextArea = uitextarea(app.VozPanel);
            app.OptionsTextArea.Value = {'1- Estrella'; '2- Fig 2'; '3- Fig 3'; '4- Mueve S1'; '5- Mueve S2'};
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.BenderLabel = uilabel(app.Panel);
            app.BenderLabel.Text = 'Bender';
            app.OkButton = uibutton(app.Panel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.LuzLabel = uilabel(app.Panel);
            app.LuzLabel.Text = 'Luz';
            app.ComodoroLabel = uilabel(app.Panel);
            app.ComodoroLabel.Text = 'Comodoro';
            app.CirculoLabel = uilabel(app.Panel);
            app.CirculoLabel.Text = 'Circulo';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '17';
            app.Label_8 = uilabel(app.Panel);
            app.recButton = uibutton(app.Panel, 'push');
            app.recButton.ButtonPushedFcn = createCallbackFcn(app, @recButtonPushed, true);
            app.recButton.Text = 'rec';
            app.operarreomotoSwitchLabel = uilabel(app.UIFigure);
            app.operarreomotoSwitchLabel.Text = 'operar reomoto';
            app.operarreomotoSwitch = uiswitch(app.UIFigure, 'slider');
            app.operarreomotoSwitch.ValueChangedFcn = createCallbackFcn(app, @operarreomotoSwitchValueChanged, true);
            app.remotoLabel = uilabel(app.UIFigure);
            app.remotoLabel.Text = 'remoto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end